classdef ManageAccount < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ResetPasswordButton    matlab.ui.control.Button
        DeleteButton           matlab.ui.control.Button
        CreateAccountPanel     matlab.ui.container.Panel
        PleaseentertheIDLabel  matlab.ui.control.Label
        TierDropDown_2         matlab.ui.control.DropDown
        PasswordEditField_2    matlab.ui.control.EditField
        IDEditField_2          matlab.ui.control.EditField
        CreateAccountButton    matlab.ui.control.Button
        InformationPanel       matlab.ui.container.Panel
        TierDropDown           matlab.ui.control.DropDown
        TierDropDownLabel      matlab.ui.control.Label
        IDEditField            matlab.ui.control.EditField
        IDEditFieldLabel       matlab.ui.control.Label
        ApplyChangesButton     matlab.ui.control.Button
        ListBox                matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        accounts;
        orgAccounts;
    end
    
    methods (Access = private)
        
        function InitialLoadAccountList(app)  
            if ~isfile('accounts.mat')                
                disp('Account information is missing or corrupted. Contact the manufacturer.')
                return;
            end

            loadedVar = load('accounts.mat');
            app.orgAccounts = loadedVar.Accounts;
            app.accounts = app.orgAccounts;
            RenewAccountList(app);
        end

        function RenewAccountList(app)
            app.ListBox.Items = fieldnames(app.accounts);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            InitialLoadAccountList(app);
            ListBoxValueChanged(app, []);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.IDEditField.Value = app.ListBox.Value;
            app.TierDropDown.Value = app.accounts.(app.ListBox.Value).Tier;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            % 최소 한명의 admin은 있어야 합니다.
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            tempAccounts = app.accounts;
            prevId = app.ListBox.Value;
            newId = app.IDEditField.Value;            
            [isValid, stateMsg] = ValidateId(newId);
            if ~isValid; uialert(app.UIFigure, stateMsg, 'Renaming error'); return; end
            
            tempAccounts.(prevId).Tier = app.TierDropDown.Value;
            TIER_ADMIN_STRING = 'Admin';            
            fieldNames = fieldnames(tempAccounts);            
            numAdmin = 0;
            for i = 1:length(fieldNames)
                numAdmin = numAdmin + strcmp(tempAccounts.(fieldNames{i}).Tier, TIER_ADMIN_STRING);                
            end
            
            if ~numAdmin
                uialert(app.UIFigure,...
                    'Admin tier shoud be exist at least 1 account',...
                    'Tier changing error');
                return;
            end

            app.accounts = renameStructField(app.accounts, prevId, newId);
            app.accounts.(newId).Tier = app.TierDropDown.Value;
            RenewAccountList(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isequal(app.accounts, app.orgAccounts); delete(app); end
            
            selection = uiconfirm(app.UIFigure, 'Apply changes?', 'Confirmation', ...
                'Options', {'OK', 'No', 'Cancel'}, ...
                'DefaultOption', 1, 'CancelOption', 3);
            if strcmp(selection, 'Cancel'); return; end
            
            if strcmp(selection, 'No'); delete(app); return; end

            Accounts = app.accounts;
            save("accounts.mat", "Accounts");
            delete(app)
        end

        % Button pushed function: ResetPasswordButton
        function ResetPasswordButtonPushed(app, event)
            PASSWORD_LENGTH = 16;            
            id = app.ListBox.Value;
            msg = sprintf('Are you sure you want to reset password for account "%s"?', id);
            selection = uiconfirm(app.UIFigure, msg, 'Confirmation', ...
                'Options', {'OK', 'Cancel'}, ...
                'DefaultOption', 1, 'CancelOption', 2, ...
                'Icon', 'warning');            
            if strcmp(selection, 'Cancel'); return; end
            
            password = GenerateRandomPassword(PASSWORD_LENGTH);
            hashedPassword = StringToHashedHex(password);
            app.accounts.(id).Password = hashedPassword;
            % hash password 한 후 app.accounts.(id).Password 에 저장            
            
            msg = sprintf('New Password\n\n%s', password);
            uiconfirm(app.UIFigure, msg, 'Confirm', ...
                'Options', {'OK'}, ...
                'DefaultOption', 1, ...
                'Icon', 'success');            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.InformationPanel = uipanel(app.UIFigure);
            app.InformationPanel.Title = 'Information';
            app.ApplyChangesButton = uibutton(app.InformationPanel, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.IDEditFieldLabel = uilabel(app.InformationPanel);
            app.IDEditFieldLabel.Text = 'ID :';
            app.IDEditField = uieditfield(app.InformationPanel, 'text');
            app.TierDropDownLabel = uilabel(app.InformationPanel);
            app.TierDropDownLabel.Text = 'Tier :';
            app.TierDropDown = uidropdown(app.InformationPanel);
            app.TierDropDown.Items = {'Admin', 'Manager', 'Senior', 'Staff'};
            app.TierDropDown.Value = 'Admin';
            app.CreateAccountPanel = uipanel(app.UIFigure);
            app.CreateAccountPanel.Title = 'Create Account';
            app.CreateAccountButton = uibutton(app.CreateAccountPanel, 'push');
            app.CreateAccountButton.Text = 'Create Account';
            app.IDEditField_2 = uieditfield(app.CreateAccountPanel, 'text');
            app.IDEditField_2.Placeholder = 'ID';
            app.PasswordEditField_2 = uieditfield(app.CreateAccountPanel, 'text');
            app.PasswordEditField_2.Placeholder = 'Password';
            app.TierDropDown_2 = uidropdown(app.CreateAccountPanel);
            app.TierDropDown_2.Items = {'Admin', 'Manager', 'Senior', 'Staff'};
            app.TierDropDown_2.Placeholder = 'Select a Tier';
            app.TierDropDown_2.Value = 'Staff';
            app.PleaseentertheIDLabel = uilabel(app.CreateAccountPanel);
            app.PleaseentertheIDLabel.Text = 'Please enter the ID';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.ResetPasswordButton = uibutton(app.UIFigure, 'push');
            app.ResetPasswordButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPasswordButtonPushed, true);
            app.ResetPasswordButton.Text = 'Reset Password';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManageAccount

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end