classdef ManageAccountForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ManageAffiliationsLabel  matlab.ui.control.Label
        AddButton                matlab.ui.control.Button
        EditField                matlab.ui.control.EditField
        Button_3                 matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        ListBox                  matlab.ui.control.ListBox
        ListBoxLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        accountFormType;
        accountFormFileName;
        accountFormVariableName;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, accountFormType, accountFormFileName, accountFormVariableName)
            app.accountFormType = accountFormType;
            app.accountFormFileName = accountFormFileName;
            app.accountFormVariableName = accountFormVariableName;
            app.ManageAffiliationsLabel.Text = sprintf('Manage %ss', app.accountFormType);
            [isValidFormFile, msg] = ManageAccountFormAction('Check', '', '', app.accountFormFileName, app.accountFormVariableName);
            if ~isValidFormFile
                disp('Check account_forms.mat file!');
                disp(msg);
                return;
            end

            tmp = load(app.accountFormFileName);
            app.ListBox.Items = tmp.(app.accountFormVariableName).(app.accountFormType);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if isempty(app.EditField.Value)
                return;
            end
            
            if strcmpi(app.accountFormType, 'Affiliation')
                [isValid, msg] = ValidateAffiliation(app.EditField.Value);
                if ~isValid
                    disp(msg);
                    return;
                end

                if sum(strcmpi(app.ListBox.Items, app.EditField.Value))
                    disp('An item that exists in an existing list');
                    return;
                end
            elseif strcmpi(app.accountFormType, 'EmailDomain')
            end
            app.ListBox.Items{end+1} = app.EditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '▲';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '▼';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '-';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.ManageAffiliationsLabel = uilabel(app.UIFigure);
            app.ManageAffiliationsLabel.Text = 'Manage Affiliations';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManageAccountForm(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end